classdef LatticeSAC < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        LatticeSACPlotandDataPanel     matlab.ui.container.Panel
        HzLabel_4                      matlab.ui.control.Label
        HzLabel_3                      matlab.ui.control.Label
        HzLabel_2                      matlab.ui.control.Label
        HzLabel_1                      matlab.ui.control.Label
        NRCEditFieldLabel              matlab.ui.control.Label
        StdDevSACEditFieldLabel        matlab.ui.control.Label
        MeanSACEditField               matlab.ui.control.NumericEditField
        StdDevSACEditField             matlab.ui.control.NumericEditField
        NRCEditField                   matlab.ui.control.NumericEditField
        FreqBand_1                     matlab.ui.control.NumericEditField
        FreqBand_2                     matlab.ui.control.NumericEditField
        FreqBand_3                     matlab.ui.control.NumericEditField
        FreqBand_4                     matlab.ui.control.NumericEditField
        MeanSACEditFieldLabel          matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
        LatticePropertiesPanel         matlab.ui.container.Panel
        CrossSectionDropDown           matlab.ui.control.DropDown
        LengthDiametermmSliderLabel    matlab.ui.control.Label
        LengthDiametermmSlider         matlab.ui.control.Slider
        LengthDiametermmSliderNumeric  matlab.ui.control.NumericEditField
        NumberofLayersSpinner          matlab.ui.control.Spinner
        ThicknessmmEditField           matlab.ui.control.NumericEditField
        FreqLabel_1                    matlab.ui.control.Label
        FreqLabel_2                    matlab.ui.control.Label
        FreqLabel_3                    matlab.ui.control.Label
        FreqLowerBoundSpinner          matlab.ui.control.Spinner
        FreqUpperBoundSpinner          matlab.ui.control.Spinner
        ThicknessmmEditFieldLabel      matlab.ui.control.Label
        NumberofLayersSpinnerLabel     matlab.ui.control.Label
        CrossSectionDropDownLabel      matlab.ui.control.Label
        LatticeLayerPropertiesPanel    matlab.ui.container.Panel
        DoubleClickLabel               matlab.ui.control.Label
        NumberofPartsSpinner           matlab.ui.control.Spinner
        NumberofPartsSpinnerLabel      matlab.ui.control.Label
        LayerNumberDropDown            matlab.ui.control.DropDown
        UITable                        matlab.ui.control.Table
        LayerNumberDropDownLabel       matlab.ui.control.Label
        LoadLatticeButton              matlab.ui.control.Button
        SaveLatticeButton              matlab.ui.control.Button
        PlotSoundAbsorptionButton      matlab.ui.control.Button
        SavePlotButton                 matlab.ui.control.Button
        ExportLatticeDesignButton      matlab.ui.control.Button
        ResetButton                    matlab.ui.control.Button
        AppTitle                       matlab.ui.control.Label
        ToUseLabel                     matlab.ui.control.Label
        ZhaiGroupLogo                  matlab.ui.control.Image
        NUSLogo                        matlab.ui.control.Image
        DesignedBy                     matlab.ui.control.Label
        VersionNum                     matlab.ui.control.Label
    end

    
    properties (Access = private)
        % Lists and Ranges
        CrossSectionList    % List of Cross-sections
        FrequencyFull       % Full Frequency Range
        
        % Objects
        CurrentLayerNum     % Current LattLayer to change
        CurrentLayerNode    % Current LattLayer to change (LLNode)
        CurrentPartNum      % Current LattLayerPart to change
        CurrentPartNode     % Current LattLayerPart to change (LLNode)
        LattLayerPartTable  % Lattice Layer - Table of Parts

        % Properties
        NumLayers           % Number of Layers for Sample
        Thickness           % Total thickness of sample
        Frequency           % Frequency Range
        NumParts            % Number of parts for CurrentLayer.
        DefaultUC           % Default Unit Cell
        DefaultCS           % Default Cell Size
        DefaultRD           % Default Relative Density
        DefaultNumLayer     % Default Number of Layers
        
        % Side Apps
        LattLayerPartProp   % LattLayerPartProperties App
        SaveLattApp         % Save Lattice App
        LoadLattApp         % Load Lattice App
        SavePlotApp         % Save SAC Plot App
        ExportLattApp       % Export Lattice App
    end
    
    properties (Access = public)
        % Libraries
        CellArchLibrary     % Library of CellArchitecture Objects
        CellArchList        % List of Cell Architectures
        LatticeLibrary      % Library of example Lattice Objects
        LatticeLibraryLabels % Library of example Lattice Objects
        
        % Lists and Ranges
        CellSize            % Range of Cell Sizes
        RelativeDensity     % Range of Relative Densities

        % Objects
        LatticeStructure    % Lattice Object (Indexed linked list)
        CurrentLayer        % Current LattLayer to change (LattLayer)
        CurrentPart         % Current LattLayerPart to change (LattLayerPart)
        
        % Properties
        CrossSection        % Sample Cross Section
        SampleLength        % Sample Length (square cross-section)
        SampleDiameter      % Sample Diameter (circular cross-section)
    end
    
    methods (Access = private)
        
        function setDefaults(app)
            % Set Lists and Ranges
            app.CrossSectionList = ["Circular";"Square"];
            app.CellSize = 4:0.1:8;
            app.RelativeDensity = 0.1:0.01:0.4;
            app.FrequencyFull = 100:10:6300;

            % Lattice Properties
            app.CrossSectionDropDown.Items = app.CrossSectionList;
            app.CrossSectionDropDown.Value = app.CrossSectionList(1);
            app.CrossSection = app.CrossSectionDropDown.Value;
            
            app.LengthDiametermmSlider.Limits = [10,50];
            app.LengthDiametermmSlider.MajorTicks = 10:10:50;
            app.LengthDiametermmSlider.MinorTicks = 10:2:50;
            app.LengthDiametermmSlider.Value = mean(app.LengthDiametermmSlider.Limits);
            app.LengthDiametermmSliderNumeric.Value = app.LengthDiametermmSlider.Value;
            app.SampleLength = app.LengthDiametermmSlider.Value;
            app.SampleDiameter = app.LengthDiametermmSlider.Value;
            
            app.NumberofLayersSpinner.Value = 1;
            app.NumberofPartsSpinner.Value = 1;
            
            app.FreqLowerBoundSpinner.Value = 1000;
            app.FreqUpperBoundSpinner.Value = 6300;

            app.defaultPart();
            
            % Initialise first LattLayer.
            app.CurrentLayerNum = 1;
            app.LayerNumberDropDown.Value = app.LayerNumberDropDown.Items(1);
            app.createLayer();
            
            % Initialise LatticeStructure.
            app.LatticeStructure = Lattice(app.CrossSection,app.FrequencyFull);
            app.LatticeStructure.insertLayer(app.CurrentLayer,app.CurrentLayerNum);

            % Set all properties on UI and main app.
            app.loadLattice(app.LatticeStructure,1);

            % Clear plot axes
            cla(app.UIAxes);
        end

        % Default LattLayerPart properties
        function defaultPart(app)
            app.DefaultUC = app.CellArchList(1);
            app.DefaultCS = mean([app.CellSize(1),app.CellSize(end)]);
            app.DefaultRD = 0.3;
            app.DefaultNumLayer = 1;
        end

        % Create new LattLayerPart.
        function createPart(app)
            app.CurrentPart = LattLayerStrut(app.DefaultUC, ...
                app.DefaultCS, ...
                app.DefaultRD, ...
                app.CrossSection,...
                app.SampleDiameter,...
                app.SampleDiameter,...
                app.DefaultNumLayer);
        end

        % Create new LattLayer.
        function createLayer(app)
            % Initialise first LattLayerPart.
            app.CurrentPartNum = 1;
            app.defaultPart();
            app.createPart();
            
            % Initialise new LattLayer.
            app.CurrentLayer = LattLayer(app.CrossSection,app.FrequencyFull);
            app.CurrentLayer.insertPart(app.CurrentPart,1);
            app.CurrentPartNode = app.CurrentLayer.Head;
        end

        % Plot sound absorption coefficient on axes.
        function plotSAC(app)
            color = [0 0.8 0; 0.8 0 0; 0 0 0.8; 0.8 0.6 0];
            line_style = ["--" ":" "-" "-."];
            marker = ['o','+','x','s'];
            
            plot(app.UIAxes,app.FrequencyFull,app.LatticeStructure.SAC, ...
                'DisplayName','Lattice SAC', ...
				'Color',[0 0 0.8],'LineStyle',"-",'LineWidth',2);
            
	        app.UIAxes.XLim = [app.Frequency(1) app.Frequency(end)];
	        app.UIAxes.YLim = [0 1];
	        app.UIAxes.YTick = 0:0.2:1.0;
	        legend(app.UIAxes,'Location','northeast','NumColumns',1);
	        legend(app.UIAxes,'FontSize',16);
	        legend(app.UIAxes,'boxoff');

            calcSACstats(app);
        end

        % Show SAC stats in UI.
        function calcSACstats(app)
            freq = app.FrequencyFull;
	        alpha = app.LatticeStructure.SAC;
	        
	        % Determine cutoffs
	        cutoff = zeros(5,1);
            freq_cutoff = [500;1000;2000;4000;6300];
	        for i = 1:length(freq)
		        for j = 1:length(freq_cutoff)
                    if freq(i) < freq_cutoff(j)
			            cutoff(j) = cutoff(j) + 1;
		            end
                end
	        end
	        
	        app.MeanSACEditField.Value = mean(alpha);
	        app.StdDevSACEditField.Value = sqrt(var(alpha));
            NRCfreq = [250; 500; 1000; 2000];
	        app.NRCEditField.Value = ...
                mean(alpha(ismember(freq,NRCfreq)));
	        app.FreqBand_1.Value = mean(alpha(cutoff(1)+1:cutoff(2)));
	        app.FreqBand_2.Value = mean(alpha(cutoff(2)+1:cutoff(3)));
            app.FreqBand_3.Value = mean(alpha(cutoff(3)+1:cutoff(4)));
	        app.FreqBand_4.Value = mean(alpha(cutoff(4)+1:cutoff(5)));
        end
    end
    
    methods (Access = public)
        
        % Load the current LatticeStructure
        function loadLattice(app,Lattice,partUpdate)
            
            % CurrentLayer is set to be the first layer of LatticeStructure.
            app.CurrentLayerNum = 1;
            app.CurrentLayerNode = Lattice.Head;
            app.CurrentLayer = app.CurrentLayerNode.Data;

            % CurrentPart is set to be the first part of LattLayer.
            app.CurrentPartNum = 1;
            app.CurrentPartNode = app.CurrentLayer.Head;
            app.CurrentPart = app.CurrentPartNode.Data;

            % Set values of properties on app UI.
            CrossSectionNum = ...
                find(app.CrossSectionList == Lattice.CrossSection);
            app.CrossSectionDropDown.Value = ...
                app.CrossSectionDropDown.Items(CrossSectionNum);
            app.LengthDiametermmSlider.Value = Lattice.l_sample;
            app.LengthDiametermmSliderNumeric.Value = ...
                app.LengthDiametermmSlider.Value;
            app.ThicknessmmEditField.Value = Lattice.t_sample;
            app.NumberofLayersSpinner.Value = Lattice.num_layers;
            app.FreqLowerBoundSpinner.Value = 1000;
            app.FreqUpperBoundSpinner.Value = 6300;
            app.NumberofPartsSpinner.Value = app.CurrentPartNum;
            app.showTable(app.CurrentLayer);

            % Set the properties within the main app.
            app.CrossSection = app.CrossSectionDropDown.Value;
            app.SampleLength = app.LengthDiametermmSlider.Value;
            app.SampleDiameter = app.LengthDiametermmSlider.Value;
            app.LengthDiametermmSliderNumeric.Value = app.LengthDiametermmSlider.Value;
            app.NumLayers = app.NumberofLayersSpinner.Value;
            app.NumParts = app.NumberofPartsSpinner.Value;
            app.LayerNumberDropDown.Items = string(1:app.NumLayers);
            app.LayerNumberDropDown.Value = ...
                app.LayerNumberDropDown.Items(app.CurrentLayerNum);
            app.Frequency = ...
                app.FreqLowerBoundSpinner.Value:10:app.FreqUpperBoundSpinner.Value;
            app.updateThickness(partUpdate);
        end

        % Update part table.
        function showTable(app,layer)
            % Create new LattLayerPartTable.
            app.LattLayerPartTable = [];
            app.LattLayerPartTable = table('Size',[0 2],...
                'VariableTypes',["double","double"], ...
                'VariableNames',["Thickness","Surface Ratio"]);
            
            % Add rows for LattLayerPartTable.
            cur = layer.Head;
            while ~isempty(cur)
                app.LattLayerPartTable(cur.Index,:) = ...
                    {cur.Data.t_part,cur.Data.SurfaceRatio};
                cur = cur.Next;
            end
            
            % Show LattLayerPartTable on UI.
            app.UITable.Data = app.LattLayerPartTable;
            app.UITable.RowName = 'numbered';
        end

        % Update thickness of sample.
        function updateThickness(app,partUpdate)
            cur = app.LatticeStructure.Head;
            while ~isempty(cur)
	            layer = cur.Data;
                layer.updateT(partUpdate);
				cur = cur.Next;
            end
            app.LatticeStructure.updateThickness();
            app.Thickness = app.LatticeStructure.t_sample;
            app.ThicknessmmEditField.Value = app.Thickness;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %#function network
            load('Unit Cell Architecture.mat');
            app.CellArchLibrary = cellArchLib;
            app.CellArchList = unit_cell; % Include in Unit Cell Arch
            
            load('Lattice Library.mat');
            app.LatticeLibrary = LattLib;
            app.LatticeLibraryLabels = LattLabel;

            app.setDefaults();
        end

        % Value changed function: CrossSectionDropDown
        function CrossSectionDropDownValueChanged(app, event)
            value = app.CrossSectionDropDown.Value;
            
            app.LatticeStructure.changeParam(value, ...
                app.LatticeStructure.l_sample, ...
                app.LatticeStructure.w_sample);

            % Set all properties on UI and main app.
            app.loadLattice(app.LatticeStructure,0);

            % For now, changing the cross section of the lattice and the
            % lattice layers has no effect. In the future, this may affect
            % the correction factors to be used in the calculation of sound
            % absorption.
        end

        % Value changed function: LengthDiametermmSlider
        function LengthDiametermmSliderValueChanged(app, event)
            value = app.LengthDiametermmSlider.Value;
            
            app.LatticeStructure.changeParam(app.CrossSection, ...
                value,value);

            % Set all properties on UI and main app.
            app.loadLattice(app.LatticeStructure,0);
            
            % For now, changing the length/diameter of the lattice and the
            % lattice layers has no effect. In the future, this may affect
            % the correction factors to be used in the calculation of sound
            % absorption.
        end

        % Value changed function: NumberofLayersSpinner
        function NumberofLayersSpinnerValueChanged(app, event)
            value = app.NumberofLayersSpinner.Value;
            
            % Add or remove Lattice Layers from the LatticeStructure.
            if app.NumLayers < value
                % Add layers
                while app.LatticeStructure.num_layers < value
                    app.createLayer();
                    app.LatticeStructure.insertLayer(app.CurrentLayer,app.LatticeStructure.num_layers + 1);
                end
            else
                % Remove layers
                while app.LatticeStructure.num_layers > value
                    app.LatticeStructure.removeLayer(app.LatticeStructure.num_layers);
                end
            end

            % Set all properties on UI and main app.
            app.loadLattice(app.LatticeStructure,0);
        end

        % Value changing function: FreqLowerBoundSpinner
        function FreqLowerBoundSpinnerValueChanging(app, event)
            changingValue = event.Value;
            if app.FreqUpperBoundSpinner.Value < changingValue + 100
                app.FreqUpperBoundSpinner.Value = ...
                    min([changingValue + 100,app.FreqUpperBoundSpinner.Limits(2)]);
            end
            app.Frequency = ...
                app.FreqLowerBoundSpinner.Value:10:app.FreqUpperBoundSpinner.Value;
        end

        % Value changing function: FreqUpperBoundSpinner
        function FreqUpperBoundSpinnerValueChanging(app, event)
            changingValue = event.Value;
            if app.FreqLowerBoundSpinner.Value > changingValue - 100
                app.FreqLowerBoundSpinner.Value = ...
                    max([changingValue - 100,app.FreqLowerBoundSpinner.Limits(1)]);
            end
            app.Frequency = ...
                app.FreqLowerBoundSpinner.Value:10:app.FreqUpperBoundSpinner.Value;
        end

        % Value changed function: LayerNumberDropDown
        function LayerNumberDropDownValueChanged(app, event)
            value = app.LayerNumberDropDown.Value;
            app.CurrentLayerNum = str2double(value);
            app.CurrentLayerNode = app.LatticeStructure.getNode(app.CurrentLayerNum);
            app.CurrentLayer = app.CurrentLayerNode.Data;
            
            app.NumParts = app.CurrentLayer.Length;
            app.NumberofPartsSpinner.Value = app.NumParts;
            
            app.CurrentPartNum = 1;
            app.CurrentPartNode = app.CurrentLayer.Head;
            app.CurrentPart = app.CurrentPartNode.Data;

            % Update LattLayerPartTable
            app.showTable(app.CurrentLayer);
        end

        % Value changing function: NumberofPartsSpinner
        function NumberofPartsSpinnerValueChanging(app, event)
            changingValue = event.Value;
            
            % Add or remove LattLayerParts from the LattLayer.
            if app.NumParts < changingValue
                % Add parts
                while app.CurrentLayer.Length < changingValue
                    app.createPart();
                    app.CurrentLayer.insertPart(app.CurrentPart, ...
                        1/(app.CurrentLayer.Length+1) );
                end
            else
                % Remove parts
                while app.CurrentLayer.Length > changingValue
                    app.CurrentLayer.removePart(app.CurrentLayer.Length);
                end
            end

            % CurrentPart is set to be the last part of LattLayer.
            app.CurrentPartNum = app.CurrentLayer.Length;
            app.CurrentPartNode = app.CurrentLayer.Tail;
            app.CurrentPart = app.CurrentPartNode.Data;

            % Update LattLayerPartTable
            app.showTable(app.CurrentLayer);
        end

        % Double-clicked callback: UITable
        function UITableDoubleClicked(app, event)
            displayRow = event.InteractionInformation.DisplayRow;

            if ~isempty(displayRow)
                app.CurrentPartNum = displayRow;
                app.CurrentPartNode = app.CurrentLayer.getNode(app.CurrentPartNum);
                app.CurrentPart = app.CurrentPartNode.Data;

                app.LattLayerPartProp = ...
                    LattPartProp(app,app.CurrentPart);
            end
        end

        % Button pushed function: LoadLatticeButton
        function LoadLatticeButtonPushed(app, event)
            app.LoadLattApp = LoadLatt(app);
        end

        % Button pushed function: SaveLatticeButton
        function SaveLatticeButtonPushed(app, event)
            app.SaveLattApp = SaveLatt(app);
        end

        % Button pushed function: PlotSoundAbsorptionButton
        function PlotSoundAbsorptionButtonPushed(app, event)
            % Refresh all properties on UI and main app.
            app.loadLattice(app.LatticeStructure,1);

            app.LatticeStructure.calcTMM();
            app.LatticeStructure.calcSAC();
            app.plotSAC();
        end

        % Button pushed function: SavePlotButton
        function SavePlotButtonPushed(app, event)
            app.SavePlotApp = SavePlot(app);
        end

        % Button pushed function: ExportLatticeDesignButton
        function ExportLatticeDesignButtonPushed(app, event)
            app.ExportLattApp = ExportLatt(app);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.setDefaults();
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app.LattLayerPartProp);
            delete(app.LoadLattApp);
            delete(app.SaveLattApp);
            delete(app.SavePlotApp);
            delete(app.ExportLattApp);
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.VersionNum = uilabel(app.UIFigure);
            app.VersionNum.Text = 'Version 1.0.0. (3 December 2023)';
            app.DesignedBy = uilabel(app.UIFigure);
            app.DesignedBy.Text = 'Designed by Chua Jun Wei for NUS Zhai Group.';
            app.NUSLogo = uiimage(app.UIFigure);
            app.NUSLogo.ImageSource = fullfile(pathToMLAPP, 'NUS Logo.png');
            app.ZhaiGroupLogo = uiimage(app.UIFigure);
            app.ZhaiGroupLogo.ImageSource = fullfile(pathToMLAPP, 'NUS Zhai Group Logo.png');
            app.ToUseLabel = uilabel(app.UIFigure);
            app.ToUseLabel.Text = 'To Use: Simply fill up all the necessary parameters (in blue) of your desired lattice structure and plot the sound absorption coefficients (SAC).';
            app.AppTitle = uilabel(app.UIFigure);
            app.AppTitle.Text = 'Lattice Sound Absorption Design Application';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.ExportLatticeDesignButton = uibutton(app.UIFigure, 'push');
            app.ExportLatticeDesignButton.ButtonPushedFcn = createCallbackFcn(app, @ExportLatticeDesignButtonPushed, true);
            app.ExportLatticeDesignButton.Text = 'Export Lattice Design';
            app.SavePlotButton = uibutton(app.UIFigure, 'push');
            app.SavePlotButton.ButtonPushedFcn = createCallbackFcn(app, @SavePlotButtonPushed, true);
            app.SavePlotButton.Text = 'Save Plot';
            app.PlotSoundAbsorptionButton = uibutton(app.UIFigure, 'push');
            app.PlotSoundAbsorptionButton.ButtonPushedFcn = createCallbackFcn(app, @PlotSoundAbsorptionButtonPushed, true);
            app.PlotSoundAbsorptionButton.Text = 'Plot Sound Absorption';
            app.SaveLatticeButton = uibutton(app.UIFigure, 'push');
            app.SaveLatticeButton.ButtonPushedFcn = createCallbackFcn(app, @SaveLatticeButtonPushed, true);
            app.SaveLatticeButton.Text = 'Save Lattice';
            app.LoadLatticeButton = uibutton(app.UIFigure, 'push');
            app.LoadLatticeButton.ButtonPushedFcn = createCallbackFcn(app, @LoadLatticeButtonPushed, true);
            app.LoadLatticeButton.Text = 'Load Lattice';
            app.LatticeLayerPropertiesPanel = uipanel(app.UIFigure);
            app.LatticeLayerPropertiesPanel.Title = 'Lattice Layer Properties';
            app.LayerNumberDropDownLabel = uilabel(app.LatticeLayerPropertiesPanel);
            app.LayerNumberDropDownLabel.Text = 'Layer Number';
            app.UITable = uitable(app.LatticeLayerPropertiesPanel);
            app.UITable.ColumnName = {'Thickness'; 'Surface Ratio'};
            app.UITable.RowName = {'''numbered'''};
            app.UITable.SelectionType = 'row';
            app.UITable.ColumnEditable = [false true];
            app.UITable.DoubleClickedFcn = createCallbackFcn(app, @UITableDoubleClicked, true);
            app.UITable.Multiselect = 'off';
            app.LayerNumberDropDown = uidropdown(app.LatticeLayerPropertiesPanel);
            app.LayerNumberDropDown.Items = {'1'};
            app.LayerNumberDropDown.ValueChangedFcn = createCallbackFcn(app, @LayerNumberDropDownValueChanged, true);
            app.LayerNumberDropDown.Value = '1';
            app.NumberofPartsSpinnerLabel = uilabel(app.LatticeLayerPropertiesPanel);
            app.NumberofPartsSpinnerLabel.Text = 'Number of Parts';
            app.NumberofPartsSpinner = uispinner(app.LatticeLayerPropertiesPanel);
            app.NumberofPartsSpinner.ValueChangingFcn = createCallbackFcn(app, @NumberofPartsSpinnerValueChanging, true);
            app.NumberofPartsSpinner.Limits = [1 6];
            app.NumberofPartsSpinner.Value = 1;
            app.DoubleClickLabel = uilabel(app.LatticeLayerPropertiesPanel);
            app.DoubleClickLabel.Text = 'Double-click each part to change its parameters.';
            app.LatticePropertiesPanel = uipanel(app.UIFigure);
            app.LatticePropertiesPanel.Title = 'Lattice Properties';
            app.CrossSectionDropDownLabel = uilabel(app.LatticePropertiesPanel);
            app.CrossSectionDropDownLabel.Text = 'Cross Section';
            app.NumberofLayersSpinnerLabel = uilabel(app.LatticePropertiesPanel);
            app.NumberofLayersSpinnerLabel.Text = 'Number of Layers';
            app.ThicknessmmEditFieldLabel = uilabel(app.LatticePropertiesPanel);
            app.ThicknessmmEditFieldLabel.Text = 'Thickness (mm)';
            app.FreqUpperBoundSpinner = uispinner(app.LatticePropertiesPanel);
            app.FreqUpperBoundSpinner.Step = 50;
            app.FreqUpperBoundSpinner.ValueChangingFcn = createCallbackFcn(app, @FreqUpperBoundSpinnerValueChanging, true);
            app.FreqUpperBoundSpinner.Limits = [50 10000];
            app.FreqUpperBoundSpinner.Tag = 'Frequency_end';
            app.FreqUpperBoundSpinner.Value = 6300;
            app.FreqLowerBoundSpinner = uispinner(app.LatticePropertiesPanel);
            app.FreqLowerBoundSpinner.Step = 50;
            app.FreqLowerBoundSpinner.ValueChangingFcn = createCallbackFcn(app, @FreqLowerBoundSpinnerValueChanging, true);
            app.FreqLowerBoundSpinner.Limits = [50 10000];
            app.FreqLowerBoundSpinner.Value = 1000;
            app.FreqLabel_3 = uilabel(app.LatticePropertiesPanel);
            app.FreqLabel_3.Text = 'Hz';
            app.FreqLabel_2 = uilabel(app.LatticePropertiesPanel);
            app.FreqLabel_2.Text = 'Hz to';
            app.FreqLabel_1 = uilabel(app.LatticePropertiesPanel);
            app.FreqLabel_1.Text = 'Frequency';
            app.ThicknessmmEditField = uieditfield(app.LatticePropertiesPanel, 'numeric');
            app.ThicknessmmEditField.Editable = 'off';
            app.ThicknessmmEditField.Value = 24;
            app.NumberofLayersSpinner = uispinner(app.LatticePropertiesPanel);
            app.NumberofLayersSpinner.Limits = [1 6];
            app.NumberofLayersSpinner.ValueChangedFcn = createCallbackFcn(app, @NumberofLayersSpinnerValueChanged, true);
            app.NumberofLayersSpinner.Value = 1;
            app.LengthDiametermmSliderNumeric = uieditfield(app.LatticePropertiesPanel, 'numeric');
            app.LengthDiametermmSliderNumeric.Value = 30;
            app.LengthDiametermmSlider = uislider(app.LatticePropertiesPanel);
            app.LengthDiametermmSlider.Limits = [10 50];
            app.LengthDiametermmSlider.MajorTicks = [10 20 30 40 50];
            app.LengthDiametermmSlider.ValueChangedFcn = createCallbackFcn(app, @LengthDiametermmSliderValueChanged, true);
            app.LengthDiametermmSlider.Value = 30;
            app.LengthDiametermmSliderLabel = uilabel(app.LatticePropertiesPanel);
            app.LengthDiametermmSliderLabel.Text = 'Length/Diameter (mm)';
            app.CrossSectionDropDown = uidropdown(app.LatticePropertiesPanel);
            app.CrossSectionDropDown.Items = {'Circular', 'Square'};
            app.CrossSectionDropDown.ValueChangedFcn = createCallbackFcn(app, @CrossSectionDropDownValueChanged, true);
            app.CrossSectionDropDown.Value = 'Circular';
            app.LatticeSACPlotandDataPanel = uipanel(app.UIFigure);
            app.LatticeSACPlotandDataPanel.Title = 'Lattice SAC Plot and Data';
            app.UIAxes = uiaxes(app.LatticeSACPlotandDataPanel);
            title(app.UIAxes, 'Lattice Sound Absorption')
            xlabel(app.UIAxes, 'Frequency (Hz)')
            ylabel(app.UIAxes, 'Sound Absorption Cefficient (\alpha)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [1000 6300];
            app.UIAxes.XTick = [1000 2000 3000 4000 5000 6000];
            app.UIAxes.XTickLabel = {'1000'; '2000'; '3000'; '4000'; '5000'; '6000'};
            app.MeanSACEditFieldLabel = uilabel(app.LatticeSACPlotandDataPanel);
            app.MeanSACEditFieldLabel.Text = 'Mean SAC';
            app.FreqBand_4 = uieditfield(app.LatticeSACPlotandDataPanel, 'numeric');
            app.FreqBand_4.Editable = 'off';
            app.FreqBand_3 = uieditfield(app.LatticeSACPlotandDataPanel, 'numeric');
            app.FreqBand_3.Editable = 'off';
            app.FreqBand_2 = uieditfield(app.LatticeSACPlotandDataPanel, 'numeric');
            app.FreqBand_2.Editable = 'off';
            app.FreqBand_1 = uieditfield(app.LatticeSACPlotandDataPanel, 'numeric');
            app.FreqBand_1.Editable = 'off';
            app.NRCEditField = uieditfield(app.LatticeSACPlotandDataPanel, 'numeric');
            app.NRCEditField.Editable = 'off';
            app.StdDevSACEditField = uieditfield(app.LatticeSACPlotandDataPanel, 'numeric');
            app.StdDevSACEditField.Editable = 'off';
            app.MeanSACEditField = uieditfield(app.LatticeSACPlotandDataPanel, 'numeric');
            app.MeanSACEditField.Editable = 'off';
            app.StdDevSACEditFieldLabel = uilabel(app.LatticeSACPlotandDataPanel);
            app.StdDevSACEditFieldLabel.Text = 'StdDev SAC';
            app.NRCEditFieldLabel = uilabel(app.LatticeSACPlotandDataPanel);
            app.NRCEditFieldLabel.Text = 'NRC';
            app.HzLabel_1 = uilabel(app.LatticeSACPlotandDataPanel);
            app.HzLabel_1.Text = '  500 - 1000 Hz';
            app.HzLabel_2 = uilabel(app.LatticeSACPlotandDataPanel);
            app.HzLabel_2.Text = '1000 - 2000 Hz';
            app.HzLabel_3 = uilabel(app.LatticeSACPlotandDataPanel);
            app.HzLabel_3.Text = '2000 - 4000 Hz';
            app.HzLabel_4 = uilabel(app.LatticeSACPlotandDataPanel);
            app.HzLabel_4.Text = '4000 - 6300 Hz';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LatticeSAC

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end