classdef SaveLatt < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        NameLatt           matlab.ui.control.EditField
        SaveLatticeButton  matlab.ui.control.Button
        CancelButton       matlab.ui.control.Button
        NameofLatticeStructureEditFieldLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        ParentApp           % Main app object
        LattLibrary         % Library of example Lattice Objects
        LattLibraryLabel    % Library of example Lattice Objects
        Lattice             % Lattice Structure
        Name                % Name of Lattice Structure
    end
    
    methods (Access = private)
        
        function saveLattLib(app)
            app.ParentApp.LatticeLibrary = app.LattLibrary;
            app.ParentApp.LatticeLibraryLabels = app.LattLibraryLabel;
            LattLib = app.LattLibrary;
            LattLabel = app.LattLibraryLabel;
            save('Lattice Library.mat','LattLib','LattLabel');
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainApp)
            % Store main app in property for CloseRequestFcn to use
            app.ParentApp = MainApp;
            app.LattLibrary = MainApp.LatticeLibrary;
            app.LattLibraryLabel = MainApp.LatticeLibraryLabels;
            
            % Update UI with input values
            app.Lattice = MainApp.LatticeStructure;
            app.Name = [];
        end

        % Value changed function: NameLatt
        function NameLattValueChanged(app, event)
            value = app.NameLatt.Value;
            app.Name = value;
        end

        % Button pushed function: SaveLatticeButton
        function SaveLatticeButtonPushed(app, event)
            app.LattLibrary.insert(app.Lattice,app.LattLibrary.Length+1);
			app.LattLibraryLabel.insert(app.Name,app.LattLibraryLabel.Length+1);
            app.saveLattLib();
            
            % Delete the dialog box
            delete(app);
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            % Delete the dialog box
            delete(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.LattLibrary.insert(app.Lattice,app.LattLibrary.Length+1);
			app.LattLibraryLabel.insert(app.Name,app.LattLibraryLabel.Length+1);
            app.saveLattLib();

            % Delete the dialog box
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.NameofLatticeStructureEditFieldLabel = uilabel(app.UIFigure);
            app.NameofLatticeStructureEditFieldLabel.Text = 'Name of Lattice Structure';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.SaveLatticeButton = uibutton(app.UIFigure, 'push');
            app.SaveLatticeButton.ButtonPushedFcn = createCallbackFcn(app, @SaveLatticeButtonPushed, true);
            app.SaveLatticeButton.Text = 'Save Lattice';
            app.NameLatt = uieditfield(app.UIFigure, 'text');
            app.NameLatt.CharacterLimits = [0 40];
            app.NameLatt.ValueChangedFcn = createCallbackFcn(app, @NameLattValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SaveLatt(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end