classdef SavePlot < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        NamePlot            matlab.ui.control.EditField
        SavePlotButton      matlab.ui.control.Button
        CancelButton        matlab.ui.control.Button
        NameofSACPlotLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        ParentApp           % Main app object
        Name                % Name of Lattice Structure
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainApp)
            % Store main app in property for CloseRequestFcn to use
            app.ParentApp = MainApp;
            
            % Update UI with input values
            app.Name = [];
        end

        % Value changed function: NamePlot
        function NamePlotValueChanged(app, event)
            value = app.NamePlot.Value;
            app.Name = value;
        end

        % Button pushed function: SavePlotButton
        function SavePlotButtonPushed(app, event)
            filename = strcat(app.Name,".tif");
            exportgraphics(app.ParentApp.UIAxes,filename,"Resolution",500);
            
            % Delete the dialog box
            delete(app);
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            % Delete the dialog box
            delete(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            filename = strcat(app.Name,".tif");
            exportgraphics(app.ParentApp.UIAxes,filename,"Resolution",500);

            % Delete the dialog box
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.NameofSACPlotLabel = uilabel(app.UIFigure);
            app.NameofSACPlotLabel.Text = 'Name of SAC Plot';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.SavePlotButton = uibutton(app.UIFigure, 'push');
            app.SavePlotButton.ButtonPushedFcn = createCallbackFcn(app, @SavePlotButtonPushed, true);
            app.SavePlotButton.Text = 'Save Plot';
            app.NamePlot = uieditfield(app.UIFigure, 'text');
            app.NamePlot.CharacterLimits = [0 40];
            app.NamePlot.ValueChangedFcn = createCallbackFcn(app, @NamePlotValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SavePlot(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end