classdef EditBoundPlate < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        DefaultBoundsButton          matlab.ui.control.Button
        EditLatticeLayerBoundsPanel  matlab.ui.container.Panel
        UnitCellsLabel               matlab.ui.control.Label
        UITable                      matlab.ui.control.Table
        CellSizemmLabel              matlab.ui.control.Label
        CellSizeMinLabel             matlab.ui.control.Label
        CellSizeMinSlider            matlab.ui.control.Slider
        CellSizeMinNumeric           matlab.ui.control.NumericEditField
        CellSizeMaxLabel             matlab.ui.control.Label
        CellSizeMaxSlider            matlab.ui.control.Slider
        CellSizeMaxNumeric           matlab.ui.control.NumericEditField
        RelativeDensityLabel         matlab.ui.control.Label
        RelativeDensityMinLabel      matlab.ui.control.Label
        RelativeDensityMinSlider     matlab.ui.control.Slider
        RelativeDensityMinNumeric    matlab.ui.control.NumericEditField
        RelativeDensityMaxLabel      matlab.ui.control.Label
        RelativeDensityMaxSlider     matlab.ui.control.Slider
        RelativeDensityMaxNumeric    matlab.ui.control.NumericEditField
        HoleSizeLabel                matlab.ui.control.Label
        HoleSizeMinLabel             matlab.ui.control.Label
        HoleSizeMinSlider            matlab.ui.control.Slider
        HoleSizeMinNumeric           matlab.ui.control.NumericEditField
        HoleSizeMaxLabel             matlab.ui.control.Label
        HoleSizeMaxSlider            matlab.ui.control.Slider
        HoleSizeMaxNumeric           matlab.ui.control.NumericEditField
        NumberofLayersSpinner        matlab.ui.control.Spinner
        NumberofLayersSpinnerLabel   matlab.ui.control.Label
        SaveBoundsButton             matlab.ui.control.Button
        ResetBoundsButton            matlab.ui.control.Button
        CancelButton                 matlab.ui.control.Button
    end

    
    properties (Access = private)
        ParentApp       % Main app object
        CurBounds       % Current Default_Plate to change.
        NewBounds       % New Default_Plate to be used.
        UnitCellTable   % Table of Unit Cells.
        PlateDefault    % Plate Lattice Default
    end
    
    methods (Access = private)
        
        function setDefaults(app)
            % Set default properties
            app.CellSizeMinSlider.Limits = ...
                [app.PlateDefault.CellSize(1),app.PlateDefault.CellSize(end)];
            app.CellSizeMinSlider.MajorTicks = ...
                app.PlateDefault.CellSize(1):1:app.PlateDefault.CellSize(end);
            app.CellSizeMinSlider.MinorTicks = ...
                app.PlateDefault.CellSize(1):0.2:app.PlateDefault.CellSize(end);

            app.CellSizeMaxSlider.Limits = ...
                [app.PlateDefault.CellSize(1),app.PlateDefault.CellSize(end)];
            app.CellSizeMaxSlider.MajorTicks = ...
                app.PlateDefault.CellSize(1):1:app.PlateDefault.CellSize(end);
            app.CellSizeMaxSlider.MinorTicks = ...
                app.PlateDefault.CellSize(1):0.2:app.PlateDefault.CellSize(end);
            
            app.RelativeDensityMinSlider.Limits = ...
                [app.PlateDefault.RelativeDensity(1),app.PlateDefault.RelativeDensity(end)];
            app.RelativeDensityMinSlider.MajorTicks = ...
                app.PlateDefault.RelativeDensity(1):0.1:app.PlateDefault.RelativeDensity(end);
            app.RelativeDensityMinSlider.MinorTicks = ...
                app.PlateDefault.RelativeDensity(1):0.02:app.PlateDefault.RelativeDensity(end);
            
            app.RelativeDensityMaxSlider.Limits = ...
                [app.PlateDefault.RelativeDensity(1),app.PlateDefault.RelativeDensity(end)];
            app.RelativeDensityMaxSlider.MajorTicks = ...
                app.PlateDefault.RelativeDensity(1):0.1:app.PlateDefault.RelativeDensity(end);
            app.RelativeDensityMaxSlider.MinorTicks = ...
                app.PlateDefault.RelativeDensity(1):0.02:app.PlateDefault.RelativeDensity(end);
            
            app.HoleSizeMinSlider.Limits = ...
                [app.PlateDefault.HolePercent(1),app.PlateDefault.HolePercent(end)];
            app.HoleSizeMinSlider.MajorTicks = ...
                app.PlateDefault.HolePercent(1):0.1:app.PlateDefault.HolePercent(end);
            app.HoleSizeMinSlider.MinorTicks = ...
                app.PlateDefault.HolePercent(1):0.02:app.PlateDefault.HolePercent(end);

            app.HoleSizeMaxSlider.Limits = ...
                [app.PlateDefault.HolePercent(1),app.PlateDefault.HolePercent(end)];
            app.HoleSizeMaxSlider.MajorTicks = ...
                app.PlateDefault.HolePercent(1):0.1:app.PlateDefault.HolePercent(end);
            app.HoleSizeMaxSlider.MinorTicks = ...
                app.PlateDefault.HolePercent(1):0.02:app.PlateDefault.HolePercent(end);

            app.initialiseBounds();
        end
        
        % Initialise lattice layer part.
        function initialiseBounds(app)
            app.showBounds(app.CurBounds);
            app.NewBounds = app.CurBounds.copyBounds();
            app.showTable();
        end

        % Show bounds on app.
        function showBounds(app,Bounds)
            if nargin == 2
                app.CellSizeMinSlider.Value = Bounds.CellSize_Min;
                app.CellSizeMaxSlider.Value = Bounds.CellSize_Max;
                
                app.RelativeDensityMinSlider.Value = Bounds.RelativeDensity_Min;
                app.RelativeDensityMaxSlider.Value = Bounds.RelativeDensity_Max;
                
                app.HoleSizeMinSlider.Value = Bounds.HolePercent_Min;
                app.HoleSizeMaxSlider.Value = Bounds.HolePercent_Max;
                
                app.NumberofLayersSpinner.Value = Bounds.NumLayer_Min;

                app.CellSizeMinNumeric.Value = app.CellSizeMinSlider.Value;
                app.CellSizeMaxNumeric.Value = app.CellSizeMaxSlider.Value;

                app.RelativeDensityMinNumeric.Value = app.RelativeDensityMinSlider.Value;
                app.RelativeDensityMaxNumeric.Value = app.RelativeDensityMaxSlider.Value;

                app.HoleSizeMinNumeric.Value = app.HoleSizeMinSlider.Value;
                app.HoleSizeMaxNumeric.Value = app.HoleSizeMaxSlider.Value;
            else
                app.showBounds(app.PlateDefault);
            end
        end

        function showTable(app)
            % Create new UnitCellTable.
            app.UnitCellTable = [];
            app.UnitCellTable = table('Size',[0 2],...
                'VariableTypes',["string","logical"], ...
                'VariableNames',["Unit Cell","Select"]);
            
            % Add rows for UnitCellTable.
            CellList = app.PlateDefault.CellArchList;
            for idx = 1:size(CellList,1)
                app.UnitCellTable(idx,:) = ...
                    {CellList(idx), ...
                    app.NewBounds.isCellArchChoice(CellList(idx))};
            end
            
            % Show LattLayerPartTable on UI.
            app.UITable.Data = app.UnitCellTable;
            app.UITable.RowName = 'numbered';
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainApp, PlateBounds, Default_Plate)
            % Store main app in property for CloseRequestFcn to use
            app.ParentApp = MainApp;
            
            % Update UI with input values
            app.CurBounds = PlateBounds;
            app.PlateDefault = Default_Plate;

            % Set app defaults.
            app.setDefaults();
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
            UC = app.UnitCellTable{indices(1),1};
            isChoiceOld = app.UnitCellTable{indices(1),2};
            isChoiceNew = newData;
            
            if isChoiceOld ~= isChoiceNew
                app.UnitCellTable{indices(1),indices(2)} = isChoiceNew;
                if isChoiceNew == true
                    app.NewBounds.addCellArchChoice(UC);
                else
                    app.NewBounds.removeCellArchChoice(UC);
                end
            end
        end

        % Value changed function: CellSizeMinSlider
        function CellSizeMinSliderValueChanged(app, event)
            value = app.CellSizeMinSlider.Value;
            if value > app.NewBounds.CellSize_Max
                value = app.NewBounds.CellSize_Max;
                app.CellSizeMinSlider.Value = value;
            end
            app.CellSizeMinNumeric.Value = value;
            app.NewBounds.setCellSizeMin(value);
        end

        % Value changed function: CellSizeMinNumeric
        function CellSizeMinNumericValueChanged(app, event)
            value = app.CellSizeMinNumeric.Value;
            if value >= app.PlateDefault.CellSize(1) && ...
                    value <= app.PlateDefault.CellSize(end)
                if value > app.NewBounds.CellSize_Max
                    value = app.NewBounds.CellSize_Max;
                    app.CellSizeMinNumeric.Value = value;
                end
                app.CellSizeMinSlider.Value = value;
                app.NewBounds.setCellSizeMin(value);
            else
                app.CellSizeMinNumeric.Value = app.CellSizeMinSlider.Value;
            end
        end

        % Value changed function: CellSizeMaxSlider
        function CellSizeMaxSliderValueChanged(app, event)
            value = app.CellSizeMaxSlider.Value;
            if value < app.NewBounds.CellSize_Min
                value = app.NewBounds.CellSize_Min;
                app.CellSizeMaxSlider.Value = value;
            end
            app.CellSizeMaxNumeric.Value = value;
            app.NewBounds.setCellSizeMax(value);
        end

        % Value changed function: CellSizeMaxNumeric
        function CellSizeMaxNumericValueChanged(app, event)
            value = app.CellSizeMaxNumeric.Value;
            if value >= app.PlateDefault.CellSize(1) && ...
                    value <= app.PlateDefault.CellSize(end)
                if value < app.NewBounds.CellSize_Min
                    value = app.NewBounds.CellSize_Min;
                    app.CellSizeMaxNumeric.Value = value;
                end
                app.CellSizeMaxSlider.Value = value;
                app.NewBounds.setCellSizeMax(value);
            else
                app.CellSizeMaxNumeric.Value = app.CellSizeMaxSlider.Value;
            end
        end

        % Value changed function: RelativeDensityMinSlider
        function RelativeDensityMinSliderValueChanged(app, event)
            value = app.RelativeDensityMinSlider.Value;
            if value > app.NewBounds.RelativeDensity_Max
                value = app.NewBounds.RelativeDensity_Max;
                app.RelativeDensityMinSlider.Value = value;
            end
            app.RelativeDensityMinNumeric.Value = value;
            app.NewBounds.setRDMin(value);
        end

        % Value changed function: RelativeDensityMinNumeric
        function RelativeDensityMinNumericValueChanged(app, event)
            value = app.RelativeDensityMinNumeric.Value;
            if value >= app.PlateDefault.RelativeDensity(1) && ...
                    value <= app.PlateDefault.RelativeDensity(end)
                if value > app.NewBounds.RelativeDensity_Max
                    value = app.NewBounds.RelativeDensity_Max;
                    app.RelativeDensityMinNumeric.Value = value;
                end
                app.RelativeDensityMinSlider.Value = value;
                app.NewBounds.setRDMin(value);
            else
                app.RelativeDensityMinNumeric.Value = app.RelativeDensityMinSlider.Value;
            end
        end

        % Value changed function: RelativeDensityMaxSlider
        function RelativeDensityMaxSliderValueChanged(app, event)
            value = app.RelativeDensityMaxSlider.Value;
            if value < app.NewBounds.RelativeDensity_Min
                value = app.NewBounds.RelativeDensity_Min;
                app.RelativeDensityMaxSlider.Value = value;
            end
            app.RelativeDensityMaxNumeric.Value = value;
            app.NewBounds.setRDMax(value);
        end

        % Value changed function: RelativeDensityMaxNumeric
        function RelativeDensityMaxNumericValueChanged(app, event)
            value = app.RelativeDensityMaxNumeric.Value;
            if value >= app.PlateDefault.RelativeDensity(1) && ...
                    value <= app.PlateDefault.RelativeDensity(end)
                if value < app.NewBounds.RelativeDensity_Min
                    value = app.NewBounds.RelativeDensity_Min;
                    app.RelativeDensityMaxNumeric.Value = value;
                end
                app.RelativeDensityMaxSlider.Value = value;
                app.NewBounds.setRDMax(value);
            else
                app.RelativeDensityMinNumeric.Value = app.RelativeDensityMinSlider.Value;
            end
        end

        % Value changed function: HoleSizeMinSlider
        function HoleSizeMinSliderValueChanged(app, event)
            value = app.HoleSizeMinSlider.Value;
            if value > app.NewBounds.HolePercent_Max
                value = app.NewBounds.HolePercent_Max;
                app.HoleSizeMinSlider.Value = value;
            end
            app.HoleSizeMinNumeric.Value = value;
            app.NewBounds.setHoleMin(value);
        end

        % Value changed function: HoleSizeMinNumeric
        function HoleSizeMinNumericValueChanged(app, event)
            value = app.HoleSizeMinNumeric.Value;
            if value >= app.PlateDefault.HolePercent(1) && ...
                    value <= app.PlateDefault.HolePercent(end)
                if value > app.NewBounds.HolePercent_Max
                    value = app.NewBounds.HolePercent_Max;
                    app.HoleSizeMinNumeric.Value = value;
                end
                app.HoleSizeMinSlider.Value = value;
                app.NewBounds.setHoleMin(value);
            else
                app.HoleSizeMinNumeric.Value = app.HoleSizeMinSlider.Value;
            end
        end

        % Value changed function: HoleSizeMaxSlider
        function HoleSizeMaxSliderValueChanged(app, event)
            value = app.HoleSizeMaxSlider.Value;
            if value < app.NewBounds.HolePercent_Min
                value = app.NewBounds.HolePercent_Min;
                app.HoleSizeMaxSlider.Value = value;
            end
            app.HoleSizeMaxNumeric.Value = value;
            app.NewBounds.setHoleMax(value);
        end

        % Value changed function: HoleSizeMaxNumeric
        function HoleSizeMaxNumericValueChanged(app, event)
            value = app.HoleSizeMaxNumeric.Value;
            if value >= app.PlateDefault.HolePercent(1) && ...
                    value <= app.PlateDefault.HolePercent(end)
                if value < app.NewBounds.HolePercent_Min
                    value = app.NewBounds.HolePercent_Min;
                    app.HoleSizeMaxNumeric.Value = value;
                end
                app.HoleSizeMaxSlider.Value = value;
                app.NewBounds.setHoleMax(value);
            else
                app.HoleSizeMinNumeric.Value = app.HoleSizeMinSlider.Value;
            end
        end

        % Value changed function: NumberofLayersSpinner
        function NumberofLayersSpinnerValueChanged(app, event)
            value = app.NumberofLayersSpinner.Value;
            app.NewBounds.setMinLayer(value);
        end

        % Button pushed function: SaveBoundsButton
        function SaveBoundsButtonPushed(app, event)
            % Update the LattLayerPart in the main app.
            app.ParentApp.updateBounds(app.NewBounds);

            % Delete the dialog box
            delete(app);
        end

        % Button pushed function: ResetBoundsButton
        function ResetBoundsButtonPushed(app, event)
            % Initialise bounds.
            app.initialiseBounds();
        end

        % Button pushed function: DefaultBoundsButton
        function DefaultBoundsButtonPushed(app, event)
            % Use PlateDefault as default bounds.
            app.NewBounds = app.PlateDefault.copyBounds();
            app.showBounds(app.NewBounds);
            app.showTable();
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            % Delete the dialog box
            delete(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % Delete the dialog box
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.ResetBoundsButton = uibutton(app.UIFigure, 'push');
            app.ResetBoundsButton.ButtonPushedFcn = createCallbackFcn(app, @ResetBoundsButtonPushed, true);
            app.ResetBoundsButton.Text = 'Reset Bounds';
            app.SaveBoundsButton = uibutton(app.UIFigure, 'push');
            app.SaveBoundsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveBoundsButtonPushed, true);
            app.SaveBoundsButton.Text = 'Save Bounds';
            app.EditLatticeLayerBoundsPanel = uipanel(app.UIFigure);
            app.EditLatticeLayerBoundsPanel.Title = 'Edit Lattice Layer Bounds';
            app.NumberofLayersSpinnerLabel = uilabel(app.EditLatticeLayerBoundsPanel);
            app.NumberofLayersSpinnerLabel.Text = 'Number of Layers';
            app.NumberofLayersSpinner = uispinner(app.EditLatticeLayerBoundsPanel);
            app.NumberofLayersSpinner.Limits = [1 Inf];
            app.NumberofLayersSpinner.ValueChangedFcn = createCallbackFcn(app, @NumberofLayersSpinnerValueChanged, true);
            app.NumberofLayersSpinner.Value = 1;
            app.HoleSizeMaxNumeric = uieditfield(app.EditLatticeLayerBoundsPanel, 'numeric');
            app.HoleSizeMaxNumeric.Limits = [0.3 0.6];
            app.HoleSizeMaxNumeric.ValueChangedFcn = createCallbackFcn(app, @HoleSizeMaxNumericValueChanged, true);
            app.HoleSizeMaxNumeric.Value = 0.6;
            app.HoleSizeMaxSlider = uislider(app.EditLatticeLayerBoundsPanel);
            app.HoleSizeMaxSlider.Limits = [0.3 0.6];
            app.HoleSizeMaxSlider.MajorTicks = [0.3 0.4 0.5 0.6];
            app.HoleSizeMaxSlider.ValueChangedFcn = createCallbackFcn(app, @HoleSizeMaxSliderValueChanged, true);
            app.HoleSizeMaxSlider.MinorTicks = [0.3 0.325 0.35 0.375 0.4 0.425 0.45 0.475 0.5 0.525 0.55 0.575 0.6];
            app.HoleSizeMaxSlider.Value = 0.6;
            app.HoleSizeMaxLabel = uilabel(app.EditLatticeLayerBoundsPanel);
            app.HoleSizeMaxLabel.Text = 'Maximum';
            app.HoleSizeMinNumeric = uieditfield(app.EditLatticeLayerBoundsPanel, 'numeric');
            app.HoleSizeMinNumeric.Limits = [0.3 0.6];
            app.HoleSizeMinNumeric.ValueChangedFcn = createCallbackFcn(app, @HoleSizeMinNumericValueChanged, true);
            app.HoleSizeMinNumeric.Value = 0.3;
            app.HoleSizeMinSlider = uislider(app.EditLatticeLayerBoundsPanel);
            app.HoleSizeMinSlider.Limits = [0.3 0.6];
            app.HoleSizeMinSlider.MajorTicks = [0.3 0.4 0.5 0.6];
            app.HoleSizeMinSlider.ValueChangedFcn = createCallbackFcn(app, @HoleSizeMinSliderValueChanged, true);
            app.HoleSizeMinSlider.MinorTicks = [0.3 0.325 0.35 0.375 0.4 0.425 0.45 0.475 0.5 0.525 0.55 0.575 0.6];
            app.HoleSizeMinSlider.Value = 0.3;
            app.HoleSizeMinLabel = uilabel(app.EditLatticeLayerBoundsPanel);
            app.HoleSizeMinLabel.Text = 'Minimum';
            app.HoleSizeLabel = uilabel(app.EditLatticeLayerBoundsPanel);
            app.HoleSizeLabel.Text = 'Hole Size (% of Cell Size)';
            app.RelativeDensityMaxNumeric = uieditfield(app.EditLatticeLayerBoundsPanel, 'numeric');
            app.RelativeDensityMaxNumeric.Limits = [0.1 0.4];
            app.RelativeDensityMaxNumeric.ValueChangedFcn = createCallbackFcn(app, @RelativeDensityMaxNumericValueChanged, true);
            app.RelativeDensityMaxNumeric.Value = 0.4;
            app.RelativeDensityMaxSlider = uislider(app.EditLatticeLayerBoundsPanel);
            app.RelativeDensityMaxSlider.Limits = [0.1 0.4];
            app.RelativeDensityMaxSlider.MajorTicks = [0.1 0.2 0.3 0.4];
            app.RelativeDensityMaxSlider.ValueChangedFcn = createCallbackFcn(app, @RelativeDensityMaxSliderValueChanged, true);
            app.RelativeDensityMaxSlider.MinorTicks = [0.1 0.12 0.14 0.16 0.18 0.2 0.22 0.24 0.26 0.28 0.3 0.32 0.34 0.36 0.38 0.4];
            app.RelativeDensityMaxSlider.Value = 0.4;
            app.RelativeDensityMaxLabel = uilabel(app.EditLatticeLayerBoundsPanel);
            app.RelativeDensityMaxLabel.Text = 'Maximum';
            app.RelativeDensityMinNumeric = uieditfield(app.EditLatticeLayerBoundsPanel, 'numeric');
            app.RelativeDensityMinNumeric.Limits = [0.1 0.4];
            app.RelativeDensityMinNumeric.ValueChangedFcn = createCallbackFcn(app, @RelativeDensityMinNumericValueChanged, true);
            app.RelativeDensityMinNumeric.Value = 0.1;
            app.RelativeDensityMinSlider = uislider(app.EditLatticeLayerBoundsPanel);
            app.RelativeDensityMinSlider.Limits = [0.1 0.4];
            app.RelativeDensityMinSlider.MajorTicks = [0.1 0.2 0.3 0.4];
            app.RelativeDensityMinSlider.ValueChangedFcn = createCallbackFcn(app, @RelativeDensityMinSliderValueChanged, true);
            app.RelativeDensityMinSlider.MinorTicks = [0.1 0.12 0.14 0.16 0.18 0.2 0.22 0.24 0.26 0.28 0.3 0.32 0.34 0.36 0.38 0.4];
            app.RelativeDensityMinSlider.Value = 0.1;
            app.RelativeDensityMinLabel = uilabel(app.EditLatticeLayerBoundsPanel);
            app.RelativeDensityMinLabel.Text = 'Minimum';
            app.RelativeDensityLabel = uilabel(app.EditLatticeLayerBoundsPanel);
            app.RelativeDensityLabel.Text = 'Relative Density';
            app.CellSizeMaxNumeric = uieditfield(app.EditLatticeLayerBoundsPanel, 'numeric');
            app.CellSizeMaxNumeric.Limits = [4 8];
            app.CellSizeMaxNumeric.ValueChangedFcn = createCallbackFcn(app, @CellSizeMaxNumericValueChanged, true);
            app.CellSizeMaxNumeric.Value = 8;
            app.CellSizeMaxSlider = uislider(app.EditLatticeLayerBoundsPanel);
            app.CellSizeMaxSlider.Limits = [4 8];
            app.CellSizeMaxSlider.MajorTicks = [4 5 6 7 8];
            app.CellSizeMaxSlider.ValueChangedFcn = createCallbackFcn(app, @CellSizeMaxSliderValueChanged, true);
            app.CellSizeMaxSlider.MinorTicks = [4 4.2 4.4 4.6 4.8 5 5.2 5.4 5.6 5.8 6 6.2 6.4 6.6 6.8 7 7.2 7.4 7.6 7.8 8];
            app.CellSizeMaxSlider.Value = 8;
            app.CellSizeMaxLabel = uilabel(app.EditLatticeLayerBoundsPanel);
            app.CellSizeMaxLabel.Text = 'Maximum';
            app.CellSizeMinNumeric = uieditfield(app.EditLatticeLayerBoundsPanel, 'numeric');
            app.CellSizeMinNumeric.Limits = [4 8];
            app.CellSizeMinNumeric.ValueChangedFcn = createCallbackFcn(app, @CellSizeMinNumericValueChanged, true);
            app.CellSizeMinNumeric.Value = 4;
            app.CellSizeMinSlider = uislider(app.EditLatticeLayerBoundsPanel);
            app.CellSizeMinSlider.Limits = [4 8];
            app.CellSizeMinSlider.MajorTicks = [4 5 6 7 8];
            app.CellSizeMinSlider.ValueChangedFcn = createCallbackFcn(app, @CellSizeMinSliderValueChanged, true);
            app.CellSizeMinSlider.MinorTicks = [4 4.2 4.4 4.6 4.8 5 5.2 5.4 5.6 5.8 6 6.2 6.4 6.6 6.8 7 7.2 7.4 7.6 7.8 8];
            app.CellSizeMinSlider.Value = 4;
            app.CellSizeMinLabel = uilabel(app.EditLatticeLayerBoundsPanel);
            app.CellSizeMinLabel.Text = 'Minimum';
            app.CellSizemmLabel = uilabel(app.EditLatticeLayerBoundsPanel);
            app.CellSizemmLabel.Text = 'Cell Size (mm)';
            app.UITable = uitable(app.EditLatticeLayerBoundsPanel);
            app.UITable.ColumnName = {'Unit Cell'; 'Select'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [false true];
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UnitCellsLabel = uilabel(app.EditLatticeLayerBoundsPanel);
            app.UnitCellsLabel.Text = 'Unit Cells';
            app.DefaultBoundsButton = uibutton(app.UIFigure, 'push');
            app.DefaultBoundsButton.ButtonPushedFcn = createCallbackFcn(app, @DefaultBoundsButtonPushed, true);
            app.DefaultBoundsButton.Text = 'Default Bounds';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EditBoundPlate(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end