classdef EditPartTPMS < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        EditLatticeLayerPartPanel   matlab.ui.container.Panel
        UnitCellDropDown            matlab.ui.control.DropDown
        NetworkDropDown             matlab.ui.control.DropDown
        NetworkDropDownLabel        matlab.ui.control.Label
        CellSizemmSliderLabel       matlab.ui.control.Label
        CellSizemmSlider            matlab.ui.control.Slider
        CellSizeNumeric             matlab.ui.control.NumericEditField
        RelativeDensitySliderLabel  matlab.ui.control.Label
        RelativeDensitySlider       matlab.ui.control.Slider
        RelativeDensityNumeric      matlab.ui.control.NumericEditField
        NumberofLayersSpinner       matlab.ui.control.Spinner
        NumberofLayersSpinnerLabel  matlab.ui.control.Label
        UnitCellDropDownLabel       matlab.ui.control.Label
        SaveLayerPartButton         matlab.ui.control.Button
        ResetPartButton             matlab.ui.control.Button
        CancelButton                matlab.ui.control.Button
    end

    
    properties (Access = private)
        ParentApp       % Main app object
        CurPart         % Current LattLayerPart to change (LattLayerPart)
        NewPart         % New LattLayerPart to be used.
        TPMSDefault     % TPMS Lattice Default
    end
    
    methods (Access = private)
        
        function setDefaults(app)
            % Set default properties
            app.UnitCellDropDown.Items = app.TPMSDefault.CellArchList;
            app.NetworkDropDown.Items = app.TPMSDefault.NetworkList;

            app.CellSizemmSlider.Limits = ...
                [app.TPMSDefault.CellSize(1),app.TPMSDefault.CellSize(end)];
            app.CellSizemmSlider.MajorTicks = ...
                app.TPMSDefault.CellSize(1):1:app.TPMSDefault.CellSize(end);
            app.CellSizemmSlider.MinorTicks = ...
                app.TPMSDefault.CellSize(1):0.2:app.TPMSDefault.CellSize(end);
            
            app.RelativeDensitySlider.Limits = ...
                [app.TPMSDefault.RelativeDensity(1),app.TPMSDefault.RelativeDensity(end)];
            app.RelativeDensitySlider.MajorTicks = ...
                app.TPMSDefault.RelativeDensity(1):0.1:app.TPMSDefault.RelativeDensity(end);
            app.RelativeDensitySlider.MinorTicks = ...
                app.TPMSDefault.RelativeDensity(1):0.02:app.TPMSDefault.RelativeDensity(end);
            
            app.initialisePart();
        end
        
        % Initialise lattice layer part.
        function initialisePart(app)
            app.showPart(app.CurPart);
            app.NewPart = app.CurPart.copyPart();
        end

        % Show lattice layer parameters on app.
        function showPart(app,part)
            if nargin == 2
                app.UnitCellDropDown.Value = part.UnitCell.CellArch.Name;
                app.NetworkDropDown.Value = part.UnitCell.CellArch.Network;
                app.CellSizemmSlider.Value = part.UnitCell.cell_length;
                app.RelativeDensitySlider.Value = part.UnitCell.rel_density;
                app.NumberofLayersSpinner.Value = part.N_z;

                app.CellSizeNumeric.Value = app.CellSizemmSlider.Value;
                app.RelativeDensityNumeric.Value = app.RelativeDensitySlider.Value;
            else
                app.defaultPart();
            end
        end

        % Default layer properties
        function defaultPart(app)
            app.UnitCellDropDown.Value = app.TPMSDefault.CellArch_0;
            app.NetworkDropDown.Value = app.TPMSDefault.Network_0;
            app.CellSizemmSlider.Value = app.TPMSDefault.CellSize_0;
            app.RelativeDensitySlider.Value = app.TPMSDefault.RelativeDensity_0;
            app.NumberofLayersSpinner.Value = app.TPMSDefault.NumLayer_0;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainApp, LattPart, Default_TPMS)
            % Store main app in property for CloseRequestFcn to use
            app.ParentApp = MainApp;
            
            % Update UI with input values
            app.CurPart = LattPart;
            app.TPMSDefault = Default_TPMS;

            % Set app defaults.
            app.setDefaults();
        end

        % Value changed function: UnitCellDropDown
        function UnitCellDropDownValueChanged(app, event)
            value = app.UnitCellDropDown.Value;
            app.NewPart.changeUC(value,app.NewPart.UnitCell.CellArch.Network);
        end

        % Value changed function: NetworkDropDown
        function NetworkDropDownValueChanged(app, event)
            value = app.NetworkDropDown.Value;
            app.NewPart.changeUC(app.NewPart.UnitCell.CellArch.Name,value);
        end

        % Value changed function: CellSizemmSlider
        function CellSizemmSliderValueChanged(app, event)
            value = app.CellSizemmSlider.Value;
            app.CellSizeNumeric.Value = value;
            app.NewPart.UnitCell.setLcell(value);
            app.NewPart.updateXYZ();
        end

        % Value changed function: CellSizeNumeric
        function CellSizeNumericValueChanged(app, event)
            value = app.CellSizeNumeric.Value;
            if value >= app.TPMSDefault.CellSize(1) && ...
                    value <= app.TPMSDefault.CellSize(end)
                app.CellSizemmSlider.Value = value;
                app.NewPart.UnitCell.setLcell(value);
                app.NewPart.updateXYZ();
            else
                app.CellSizeNumeric.Value = app.CellSizemmSlider.Value;
            end
        end

        % Value changed function: RelativeDensitySlider
        function RelativeDensitySliderValueChanged(app, event)
            value = app.RelativeDensitySlider.Value;
            app.RelativeDensityNumeric.Value = value;
            app.NewPart.UnitCell.setRD(value);
        end

        % Value changed function: RelativeDensityNumeric
        function RelativeDensityNumericValueChanged(app, event)
            value = app.RelativeDensityNumeric.Value;
            if value >= app.TPMSDefault.RelativeDensity(1) && ...
                    value <= app.TPMSDefault.RelativeDensity(end)
                app.RelativeDensitySlider.Value = value;
                app.NewPart.UnitCell.setRD(value);
            else
                app.RelativeDensityNumeric.Value = app.RelativeDensitySlider.Value;
            end
        end

        % Value changed function: NumberofLayersSpinner
        function NumberofLayersSpinnerValueChanged(app, event)
            value = app.NumberofLayersSpinner.Value;
            app.NewPart.setNz(value);
        end

        % Button pushed function: SaveLayerPartButton
        function SaveLayerPartButtonPushed(app, event)
            % Update the LattLayerPart in the main app.
            app.ParentApp.updatePart(app.NewPart);

            % Delete the dialog box
            delete(app);
        end

        % Button pushed function: ResetPartButton
        function ResetPartButtonPushed(app, event)
            % Initialise lattice layer part.
            app.initialisePart();
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            % Delete the dialog box
            delete(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % Delete the dialog box
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.ResetPartButton = uibutton(app.UIFigure, 'push');
            app.ResetPartButton.ButtonPushedFcn = createCallbackFcn(app, @ResetPartButtonPushed, true);
            app.ResetPartButton.Text = 'Reset Part';
            app.SaveLayerPartButton = uibutton(app.UIFigure, 'push');
            app.SaveLayerPartButton.ButtonPushedFcn = createCallbackFcn(app, @SaveLayerPartButtonPushed, true);
            app.SaveLayerPartButton.Text = 'Save Layer Part';
            app.EditLatticeLayerPartPanel = uipanel(app.UIFigure);
            app.EditLatticeLayerPartPanel.Title = 'Edit Lattice Layer Part';
            app.UnitCellDropDownLabel = uilabel(app.EditLatticeLayerPartPanel);
            app.UnitCellDropDownLabel.Text = 'Unit Cell';
            app.NumberofLayersSpinnerLabel = uilabel(app.EditLatticeLayerPartPanel);
            app.NumberofLayersSpinnerLabel.Text = 'Number of Layers';
            app.NumberofLayersSpinner = uispinner(app.EditLatticeLayerPartPanel);
            app.NumberofLayersSpinner.Limits = [0 Inf];
            app.NumberofLayersSpinner.ValueChangedFcn = createCallbackFcn(app, @NumberofLayersSpinnerValueChanged, true);
            app.RelativeDensityNumeric = uieditfield(app.EditLatticeLayerPartPanel, 'numeric');
            app.RelativeDensityNumeric.Limits = [0.1 0.4];
            app.RelativeDensityNumeric.ValueChangedFcn = createCallbackFcn(app, @RelativeDensityNumericValueChanged, true);
            app.RelativeDensityNumeric.Value = 0.3;
            app.RelativeDensitySlider = uislider(app.EditLatticeLayerPartPanel);
            app.RelativeDensitySlider.Limits = [0.1 0.4];
            app.RelativeDensitySlider.MajorTicks = [0.1 0.2 0.3 0.4];
            app.RelativeDensitySlider.ValueChangedFcn = createCallbackFcn(app, @RelativeDensitySliderValueChanged, true);
            app.RelativeDensitySlider.MinorTicks = [0.1 0.12 0.14 0.16 0.18 0.2 0.22 0.24 0.26 0.28 0.3 0.32 0.34 0.36 0.38 0.4];
            app.RelativeDensitySlider.Value = 0.3;
            app.RelativeDensitySliderLabel = uilabel(app.EditLatticeLayerPartPanel);
            app.RelativeDensitySliderLabel.Text = 'Relative Density';
            app.CellSizeNumeric = uieditfield(app.EditLatticeLayerPartPanel, 'numeric');
            app.CellSizeNumeric.Limits = [4 8];
            app.CellSizeNumeric.ValueChangedFcn = createCallbackFcn(app, @CellSizeNumericValueChanged, true);
            app.CellSizeNumeric.Value = 6;
            app.CellSizemmSlider = uislider(app.EditLatticeLayerPartPanel);
            app.CellSizemmSlider.Limits = [4 8];
            app.CellSizemmSlider.MajorTicks = [4 5 6 7 8];
            app.CellSizemmSlider.ValueChangedFcn = createCallbackFcn(app, @CellSizemmSliderValueChanged, true);
            app.CellSizemmSlider.MinorTicks = [4 4.2 4.4 4.6 4.8 5 5.2 5.4 5.6 5.8 6 6.2 6.4 6.6 6.8 7 7.2 7.4 7.6 7.8 8];
            app.CellSizemmSlider.Value = 6;
            app.CellSizemmSliderLabel = uilabel(app.EditLatticeLayerPartPanel);
            app.CellSizemmSliderLabel.Text = 'Cell Size (mm)';
            app.NetworkDropDownLabel = uilabel(app.EditLatticeLayerPartPanel);
            app.NetworkDropDownLabel.Text = 'Network';
            app.NetworkDropDown = uidropdown(app.EditLatticeLayerPartPanel);
            app.NetworkDropDown.Items = {'Sheet', 'Solid'};
            app.NetworkDropDown.ValueChangedFcn = createCallbackFcn(app, @NetworkDropDownValueChanged, true);
            app.NetworkDropDown.Value = 'Sheet';
            app.UnitCellDropDown = uidropdown(app.EditLatticeLayerPartPanel);
            app.UnitCellDropDown.Items = {'Gyroid', 'Diamond', 'Primitive', 'I-WP'};
            app.UnitCellDropDown.ValueChangedFcn = createCallbackFcn(app, @UnitCellDropDownValueChanged, true);
            app.UnitCellDropDown.Value = 'Gyroid';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EditPartTPMS(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end